classdef TBCYarnPathGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PlotButton                      matlab.ui.control.Button
        PatternoftheTBCButtonGroup      matlab.ui.container.ButtonGroup
        DiamondButton                   matlab.ui.control.RadioButton
        RegularButton                   matlab.ui.control.RadioButton
        HerculesButton                  matlab.ui.control.RadioButton
        WritetotxtfileButton            matlab.ui.control.Button
        InputGeometricalVariablesPanel  matlab.ui.container.Panel
        NumberofTurnsSpinnerLabel       matlab.ui.control.Label
        NumberofTurnsSpinner            matlab.ui.control.Spinner
        BraidAngleSpinnerLabel          matlab.ui.control.Label
        BraidAngleSpinner               matlab.ui.control.Spinner
        NumberofYarnsNSpinnerLabel      matlab.ui.control.Label
        NumberofYarnsNSpinner           matlab.ui.control.Spinner
        DiameterofMandrelmmSpinnerLabel  matlab.ui.control.Label
        DiameterofMandrelmmSpinner      matlab.ui.control.Spinner
        DiameterofStrandmmSpinnerLabel  matlab.ui.control.Label
        DiameterofStrandmmSpinner       matlab.ui.control.Spinner
        HeightoftheTBCmmSpinnerLabel    matlab.ui.control.Label
        HeightoftheTBCmmSpinner         matlab.ui.control.Spinner
        LoadingXYZpointsPanel           matlab.ui.container.Panel
        LoadButton                      matlab.ui.control.StateButton
        PlotButton_2                    matlab.ui.control.StateButton
        UITable                         matlab.ui.control.Table
        CWCheckBox                      matlab.ui.control.CheckBox
        CCWCheckBox                     matlab.ui.control.CheckBox
        Lamp                            matlab.ui.control.Lamp
        LoadPropertiesFileButton        matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end


    properties (Access = private)

        Braid_CW1
        Braid_CW2
        Braid_CCW1
        Braid_CCW2
        Height  %Height of the braid
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Visible = 'off';
            app.PlotButtonPushed
            imshow('braidedCompositesLogo-300x300.png','Parent',app.UIAxes2);
        end

        % Callback function: BraidAngleSpinner, BraidAngleSpinner, 
        % DiameterofMandrelmmSpinner, DiameterofMandrelmmSpinner, 
        % DiameterofStrandmmSpinner, HeightoftheTBCmmSpinner, 
        % NumberofTurnsSpinner, NumberofYarnsNSpinner, 
        % NumberofYarnsNSpinner, PlotButton
        function PlotButtonPushed(app, event)
                
                Resolution = 1;
                nt = app.NumberofTurnsSpinner.Value;
                np = nt*100; % number of periods
                N = app.NumberofYarnsNSpinner.Value;
                if app.NumberofYarnsNSpinner.Value == 2 ||  app.NumberofYarnsNSpinner.Value == 4 || app.NumberofYarnsNSpinner.Value == 8 || app.NumberofYarnsNSpinner.Value == 16 || app.NumberofYarnsNSpinner.Value == 32 || app.NumberofYarnsNSpinner.Value == 64 || app.NumberofYarnsNSpinner.Value == 128 || app.NumberofYarnsNSpinner.Value == 258
                    app.Lamp.Color = 'g';
                else
                    app.Lamp.Color = 'r';
                end
                    
                
                alpha = deg2rad(app.BraidAngleSpinner.Value);
                D = app.DiameterofMandrelmmSpinner.Value;
                d = app.DiameterofStrandmmSpinner.Value;
                
                n = N/2; %number of strands moving in the same direction
                Xof = 0;
                Yof = 0;
                % Zof = 1;
                Start = deg2rad(0);
                End = deg2rad(360);
                ang = deg2rad(0); %angle of rotation
                
                
                gama = 2*pi/n; %shift angle between the two consecutive strands
                R = (D/2+d);%effective radius of the helical strands
%                 End = np*gama;
                % In_Angle = pi/4;
                % End_Angle = pi/2;
                
                
                step_size = 0.001;
                
                %% forming R(theta1) & R(theta2)
                
                % theta = (Q(4)+Step_size:Step_size:Q(5));
                theta = 0:step_size:2*pi*np;
                theta1 = Start:step_size:2*pi*nt;
                
                s=ang/step_size;
                % e=200;
                % theta1 = s:
                
                %% Diamond Pattern
                if(app.DiamondButton.Value)
                    for i = 1:length(theta) 
                        Rcw1(i) = d/2*sin(N*theta(i)/2+pi/2);
                        Rccw1(i) = -d/2*sin(N*theta(i)/2+pi/2);
                    end
                    Rcw2 = Rcw1;
                    Rccw2 = Rccw1;
                %% Regular pattern                  
                elseif(app.RegularButton.Value)
                %  clock wise
                for i = 1:length(theta)
                    for j=1:np
                %         i
                %         j
                        if theta(i)>=(2*j-2)*gama && theta(i)<(2*j-1.5)*gama
                            Rcw1(i) = -d/2;
                            j=1;
                            break 
                        elseif theta(i)>=(2*j-1.5)*gama && theta(i)<(2*j-1)*gama
                            Rcw1(i) = d/2*sin(N*theta(i)/2+pi/2);
                            j=1;
                            break
                        elseif theta(i)>=(2*j-1)*gama && theta(i)<(2*j-0.5)*gama
                            Rcw1(i) = d/2;
                            j=1;
                            break
                        elseif theta(i)>=(2*j-0.5)*gama && theta(i)<2*j*gama
                            Rcw1(i) = -d/2*sin(N*theta(i)/2+pi/2);
                            j=1;
                            break
                        end
                    end
                end
                
                for i = 1:length(theta)
                    for j=1:np
                %         i
                %         j
                        if theta(i)>=(2*j-2)*gama && theta(i)<(2*j-1.5)*gama
                            Rcw2(i) = d/2;
                            j=1;
                            break 
                        elseif theta(i)>=(2*j-1.5)*gama && theta(i)<(2*j-1)*gama
                            Rcw2(i) = -d/2*sin(N*theta(i)/2+pi/2);
                            j=1;
                            break
                        elseif theta(i)>=(2*j-1)*gama && theta(i)<(2*j-0.5)*gama
                            Rcw2(i) = -d/2;
                            j=1;
                            break
                        elseif theta(i)>=(2*j-0.5)*gama && theta(i)<2*j*gama
                            Rcw2(i) = d/2*sin(N*theta(i)/2+pi/2);
                            j=1;
                            break
                        end
                    end
                end
                
                % counter clockwise
                for i = 1:length(theta)
                    for j=1:np
                        if theta(i)>=(2*j-2)*gama && theta(i)<(2*j-1.5)*gama
                            Rccw1(i) = d/2*sin(N*theta(i)/2+pi/2);
                            j=1;
                            break 
                        elseif theta(i)>=(2*j-1.5)*gama && theta(i)<(2*j-1)*gama
                            Rccw1(i) = -d/2;
                            j=1;
                            break
                        elseif theta(i)>=(2*j-1)*gama && theta(i)<(2*j-0.5)*gama
                            Rccw1(i) = -d/2*sin(N*theta(i)/2+pi/2);
                            j=1;
                            break
                        elseif theta(i)>=(2*j-0.5)*gama && theta(i)<2*j*gama
                            Rccw1(i) = d/2;
                            j=1;
                            break
                        end
                    end
                end
                
                for i = 1:length(theta)
                    for j=1:np
                        if theta(i)>=(2*j-2)*gama && theta(i)<(2*j-1.5)*gama
                            Rccw2(i) = -d/2*sin(N*theta(i)/2+pi/2);
                            j=1;
                            break 
                        elseif theta(i)>=(2*j-1.5)*gama && theta(i)<(2*j-1)*gama
                            Rccw2(i) = d/2;
                            j=1;
                            break
                        elseif theta(i)>=(2*j-1)*gama && theta(i)<(2*j-0.5)*gama
                            Rccw2(i) = d/2*sin(N*theta(i)/2+pi/2);
                            j=1;
                            break
                        elseif theta(i)>=(2*j-0.5)*gama && theta(i)<2*j*gama
                            Rccw2(i) = -d/2;
                            j=1;
                            break
                        end
                    end
                end
                
                %% Hercules Pattern
                elseif(app.HerculesButton.Value)
                  for i = 1:length(theta)
                    for j=1:np
                %         i
                %         j
                        if theta(i)>=(3*j-3)*gama && theta(i)<(3*j-2.5)*gama
                            Rcw1(i) = d/2;
                            j=1;
                            break 
                        elseif theta(i)>=(3*j-2.5)*gama && theta(i)<(3*j-2)*gama
                            Rcw1(i) = d/2;
                            j=1;
                            break
                        elseif theta(i)>=(3*j-2)*gama && theta(i)<(3*j-1.5)*gama
                            Rcw1(i) = d/2*sin(N*theta(i)/2+pi/2);
                            j=1;
                            break
                        elseif theta(i)>=(3*j-1.5)*gama && theta(i)<(3*j-1)*gama
                            Rcw1(i) = -d/2;
                            j=1;
                            break 
                        elseif theta(i)>=(3*j-1)*gama && theta(i)<(3*j-0.5)*gama
                            Rcw1(i) = -d/2;
                            j=1;
                            break
                        elseif theta(i)>=(3*j-0.5)*gama && theta(i)<3*j*gama
                            Rcw1(i) = d/2*sin(N*theta(i)/2+pi/2);
                            j=1;
                            break
                        end
                    end
                  end
                  Rcw2 = Rcw1;
                  %Counter Clockwise  
                    for i = 1:length(theta)
                        for j=1:np
                            if theta(i)>=(3*j-3)*gama && theta(i)<(3*j-2.5)*gama
                                Rccw1(i) = -d/2;
                                j=1;
                                break 
                            elseif theta(i)>=(3*j-2.5)*gama && theta(i)<(3*j-2)*gama
                                Rccw1(i) = -d/2;
                                j=1;
                                break
                            elseif theta(i)>=(3*j-2)*gama && theta(i)<(3*j-1.5)*gama
                                Rccw1(i) = -d/2*sin(N*theta(i)/2+pi/2);
                                j=1;
                                break
                            elseif theta(i)>=(3*j-1.5)*gama && theta(i)<(3*j-1)*gama
                                Rccw1(i) = d/2;
                                j=1;
                                break 
                            elseif theta(i)>=(3*j-1)*gama && theta(i)<(3*j-0.5)*gama
                                Rccw1(i) = d/2;
                                j=1;
                                break
                            elseif theta(i)>=(3*j-0.5)*gama && theta(i)<3*j*gama
                                Rccw1(i) = -d/2*sin(N*theta(i)/2+pi/2);
                                j=1;
                                break
                            end
                        end
                    end
                    Rccw2 = Rccw1;
                    
                end


                %% Creating Simulation Path
                
                Rcw1G = Rcw1(1+s:length(theta1)+s);
                Rcw2G = Rcw2(1+s:length(theta1)+s);
                Rccw1G = Rccw1(1+s:length(theta1)+s);
                Rccw2G = Rccw2(1+s:length(theta1)+s);
%                 figure()
%                 plot(Rccw1(1:300),'r')
%                 hold on
%                 plot(Rcw1(1:300),'b')
%                 figure(1)
%                 hold off
                
                    %CW1
                for i=1:n/2
                    Xcw1(:,i) = (R+Rcw1G).*cos(theta1+(i-1)*2*gama)+Xof;
                    Ycw1(:,i) = (R+Rcw1G).*sin(theta1+(i-1)*2*gama)+Yof;
                    Zcw1(:,i) =  R*theta1*cot(alpha);
                    app.Braid_CW1 = [Xcw1(:,i), Ycw1(:,i), Zcw1(:,i)];
                    figure(1)
                    plot3(Xcw1(:,i), Ycw1(:,i), Zcw1(:,i), 'bl', 'linewidth',app.DiameterofStrandmmSpinner.Value)
                    hold on
                end
                    %CW2
                for i=1:n/2
                    Xcw2(:,i) = (R+Rcw2G).*cos(theta1+(2*i-1)*gama)+Xof;
                    Ycw2(:,i) = (R+Rcw2G).*sin(theta1+(2*i-1)*gama)+Yof;
                    Zcw2(:,i) =  R*theta1*cot(alpha);
                    app.Braid_CW2 = [Xcw2(:,i), Ycw2(:,i), Zcw2(:,i)];
                    plot3(Xcw2(:,i), Ycw2(:,i), Zcw2(:,i), 'bl', 'linewidth',app.DiameterofStrandmmSpinner.Value)
                end
                    
                
                % xR =  (X-Xof)*cos(ang) + (Y-Yof)*sin(ang)+Xof;
                % yR = -(X-Xof)*sin(ang) + (Y-Yof)*cos(ang)+Yof;
                
                    % CCW1
                for i=1:n/2
                    Xccw1(:,i) = (R+Rccw1G).*cos(theta1+(i-1)*2*gama)+Xof;
                    Yccw1(:,i) = -(R+Rccw1G).*sin(theta1+(i-1)*2*gama)+Yof;
                    Zccw1(:,i) =  R*theta1*cot(alpha);
                    app.Braid_CCW1 = [Xccw1(:,i), Yccw1(:,i), Zccw1(:,i)];%, ZZ(:,1)];
                    plot3(Xccw1(:,i), Yccw1(:,i), Zccw1(:,i), 'r', 'linewidth',app.DiameterofStrandmmSpinner.Value)
                end
                    % CCW2
                for i=1:n/2
                    Xccw2(:,i) =  (R+Rccw2G).*cos(theta1+(2*i-1)*gama)+Xof;
                    Yccw2(:,i) = -(R+Rccw2G).*sin(theta1+(2*i-1)*gama)+Yof;
                    Zccw2(:,i) =  R*theta1*cot(alpha);
                    app.Braid_CCW2 = [Xccw2(:,i), Yccw2(:,i), Zccw2(:,i)];%, ZZ(:,1)];
                    plot3(Xccw2(:,i), Yccw2(:,i), Zccw2(:,i), 'r', 'linewidth',app.DiameterofStrandmmSpinner.Value)
                end
                
                hold off
                xlabel('X (mm)','FontWeight','bold','FontSize',14,'FontName','Times New Roman')
                ylabel('Y (mm)','FontWeight','bold','FontSize',14,'FontName','Times New Roman')
                zlabel('Z (mm)','FontWeight','bold','FontSize',14,'FontName','Times New Roman')
                
                if app.CWCheckBox.Value == 1 && app.CCWCheckBox.Value == 0
                    plot3(Xccw1(:,1), Yccw1(:,1), Zccw1(:,1), 'bl', 'linewidth',app.DiameterofStrandmmSpinner.Value ,'parent',app.UIAxes)
                elseif app.CCWCheckBox.Value == 1 && app.CWCheckBox.Value == 0
                    plot3(Xcw1(:,1), Ycw1(:,1), Zcw1(:,1), 'r', 'linewidth',app.DiameterofStrandmmSpinner.Value ,'parent',app.UIAxes)
                elseif app.CWCheckBox.Value == 1 && app.CCWCheckBox.Value == 1
                    plot3(app.UIAxes, Xccw1(:,1), Yccw1(:,1), Zccw1(:,1), 'bl', 'linewidth',app.DiameterofStrandmmSpinner.Value)
                    hold (app.UIAxes, 'on')
                    plot3(app.UIAxes, Xcw1(:,1), Ycw1(:,1), Zcw1(:,1), 'r', 'linewidth',app.DiameterofStrandmmSpinner.Value)
                    hold (app.UIAxes, 'off')
                end
                
                
                if nt == 1  %Save the height of one turn as a reference for calculation of relevant number of turn of the desired Height
                app.Height = max(Zccw1(:,1));
                app.HeightoftheTBCmmSpinner.Value = app.Height;
                end
                
                app.HeightoftheTBCmmSpinner.Value = app.Height*nt;
%                   plot3(XX(:,1), YY(:,1), ZZ(:,1), 'r', 'linewidth',.88,'parent',app.UIAxes)  
                
                % str = round(length(S)*Start/(2*pi));
                % en = round(length(S)*End/(2*pi));
                
                % CCW =  [(X (Start:End,1)), (Y (Start:End,1))]; % , (Z (:,1))];
                % CCWR =  [(xR (Start:End,1)), (yR (Start:End,1))]; % , (Z (:,1))];
                
                % CW = [(xR(str:en,1)), (yR(str:en,1))]; %, (ZZ(:,1))];
                % xR1 = CW(:,1);
                % yR1 = CW(:,2);
                % xq = linspace(xR1(1),xR1(end),length(S));
                % vq1 = interp1(xR1,yR1,xq);
                % Error=sum((S-[xq; vq1]').^2)/length(theta)
                
                
                %% plot both of paths
                % plot(CCW(:,1), CCW(:,2), 'DisplayName','Simulation','color','g')
%                 hold on
                % plot(CCWR(:,1), CCWR(:,2), 'DisplayName','Simulation_Rotated','Color','black')
                % plot(X(:,1), Y(:,1), 'DisplayName','Simulation_Rotated','Color','r')
                % plot(S(:,1), S(:,2), 'DisplayName','uct','Color',[1 0 0])
                % xlim([0 30]);
                % ylim([0 30]);
        
        end

        % Button pushed function: WritetotxtfileButton
        function WritetotxtfileButtonPushed(app, event)
            [file,path] = uiputfile('Sample.txt');
            cd (path);
            CW1_file = insertBefore(file,".txt","_CW1");
            fileID = fopen(CW1_file,'w');
                        % fprintf(fileID,'%6s %12s %18s\n','X','Y','Z');
            fprintf(fileID,'%6.8f %12.8f %12.8f\n',app.Braid_CW1');
            fclose(fileID);
            
            CW2_file = insertBefore(file,".txt","_CW2");
            fileID = fopen(CW2_file,'w');
                        % fprintf(fileID,'%6s %12s %18s\n','X','Y','Z');
            fprintf(fileID,'%6.8f %12.8f %12.8f\n',app.Braid_CW2');
            fclose(fileID);
            
            CCW1_file = insertBefore(file,".txt","_CCW1");
            fileID = fopen(CCW1_file,'w');
            % fprintf(fileID,'%6s %12s %18s\n','X','Y','Z');
            fprintf(fileID,'%6.8f %12.8f %12.8f\n',app.Braid_CCW1');
            fclose(fileID);
            
            CCW2_file = insertBefore(file,".txt","_CCW2");
            fileID = fopen(CCW2_file,'w');
            % fprintf(fileID,'%6s %12s %18s\n','X','Y','Z');
            fprintf(fileID,'%6.8f %12.8f %12.8f\n',app.Braid_CCW2');
            fclose(fileID);
            
            time = datestr(clock,'YYYY/mm/dd HH:MM:SS');
            
            Prop_file = insertBefore(file,".txt","_prop");
            fileID = fopen(Prop_file,'w');
            
            if( app.DiamondButton.Value)
                pattern = 'Diamond';
            elseif(app.RegularButton.Value)
                pattern = 'Regular';
            elseif(app.HerculesButton.Value)
                pattern = 'Hercules';
            end
            
            fprintf(fileID,'This file shows the input properties used for designing the Tubular Braided Composite.\n');
            fprintf(fileID,'%23s\n\n',time);
            C = {'patter of the Braid:',pattern; 'Number of Yarns:',app.NumberofYarnsNSpinner.Value;'Braid Angle (degree):',app.BraidAngleSpinner.Value;...
                'Diameter of Mandrel(mm):',app.DiameterofMandrelmmSpinner.Value;'Diameter of Strand (mm):',app.DiameterofStrandmmSpinner.Value;...
                'Length of the braid(mm):',app.HeightoftheTBCmmSpinner.Value};
            
            D = cellfun(@num2str,C,'uni',0).';
            fprintf(fileID, '%s\t%s\n',D{:});
            fclose(fileID);
          
        end

        % Value changed function: LoadButton
        function LoadButtonValueChanged(app, event)
            [file,path] = uigetfile('*.txt');
            ss= dir(fullfile(path,'*.tif')); % pattern to match filenames.
            fileID = fopen(file,'r');
            S = fscanf(fileID, '%f %f %f\n', [3,Inf]);
            app.UITable.Data = S';
            app.UITable.Visible = 'on';
%             app.S = S';
        end

        % Value changed function: PlotButton_2
        function PlotButton_2ValueChanged(app, event)
            value = app.PlotButton_2.Value;
            plot3(app.UIAxes,app.UITable.Data(:,1), app.UITable.Data(:,2), app.UITable.Data(:,3), 'bl', 'linewidth',app.DiameterofStrandmmSpinner.Value)

        end

        % Selection changed function: PatternoftheTBCButtonGroup
        function PatternoftheTBCButtonGroupSelectionChanged(app, event)
            selectedButton = app.PatternoftheTBCButtonGroup.SelectedObject;
            
        end

        % Callback function
        function TestButtonPushed(app, event)
            if(app.DiamondButton.Value)
                app.Label.Text = 'Diamond';
            elseif(app.RegularButton.Value)
                    app.Label.Text = 'Regular';
            elseif(app.HerculesButton.Value)
                app.Label.Text = 'Hercular';
            end
                
                
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            
        end

        % Value changed function: HeightoftheTBCmmSpinner
        function HeightoftheTBCmmSpinnerValueChanged(app, event)
            value = app.HeightoftheTBCmmSpinner.Value;
            app.NumberofTurnsSpinner.Value = value/app.Height;
            app.PlotButtonPushed
        end

        % Value changed function: CWCheckBox
        function CWCheckBoxValueChanged(app, event)
            value = app.CWCheckBox.Value;
            
        end

        % Callback function
        function WriteCCWpathtotxtfileButtonPushed(app, event)
            [file,path] = uiputfile('Sample.txt');
            cd (path);
            fileID = fopen(file,'w');
                        % fprintf(fileID,'%6s %12s %18s\n','X','Y','Z');
            fprintf(fileID,'%6.8f %12.8f %12.8f\n',app.Braid_CW');
            fclose(fileID);
            
            fileID = fopen('Regular Braid CCW.txt','w');
            % fprintf(fileID,'%6s %12s %18s\n','X','Y','Z');
            fprintf(fileID,'%6.8f %12.8f %12.8f\n',app.Braid_CCW');
            fclose(fileID);
        end

        % Button pushed function: LoadPropertiesFileButton
        function LoadPropertiesFileButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            dir(fullfile(path,'*.tif')); % pattern to match filenames.
            table = readtable(file,'ReadVariableNames',false);
            pattern = string(table.Var2(1));
            
            
            app.NumberofYarnsNSpinner.Value = cellfun(@str2num,table.Var2(2));
            app.BraidAngleSpinner.Value = cellfun(@str2num,table.Var2(3));
            app.DiameterofMandrelmmSpinner.Value = cellfun(@str2num,table.Var2(4));
            app.DiameterofStrandmmSpinner.Value = cellfun(@str2num,table.Var2(5));
            app.HeightoftheTBCmmSpinner.Value = cellfun(@str2num,table.Var2(6));
            
            if pattern == 'Diamond'
                app.DiamondButton.Value = 1;
            elseif pattern == 'Regular'
                app.RegularButton.Value = 1;
            elseif pattern == 'Hercules'
                app.HerculesButton.Value = 1;
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PatternoftheTBCButtonGroup = uibuttongroup(app.UIFigure);
            app.PatternoftheTBCButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PatternoftheTBCButtonGroupSelectionChanged, true);
            app.PatternoftheTBCButtonGroup.Title = 'Pattern of the TBC';
            app.DiamondButton = uiradiobutton(app.PatternoftheTBCButtonGroup);
            app.DiamondButton.Text = 'Diamond';
            app.DiamondButton.Value = true;
            app.RegularButton = uiradiobutton(app.PatternoftheTBCButtonGroup);
            app.RegularButton.Text = 'Regular';
            app.HerculesButton = uiradiobutton(app.PatternoftheTBCButtonGroup);
            app.HerculesButton.Text = 'Hercules';
            app.WritetotxtfileButton = uibutton(app.UIFigure, 'push');
            app.WritetotxtfileButton.ButtonPushedFcn = createCallbackFcn(app, @WritetotxtfileButtonPushed, true);
            app.WritetotxtfileButton.Text = 'Write to .txt file';
            app.InputGeometricalVariablesPanel = uipanel(app.UIFigure);
            app.InputGeometricalVariablesPanel.Title = 'Input Geometrical Variables';
            app.NumberofTurnsSpinnerLabel = uilabel(app.InputGeometricalVariablesPanel);
            app.NumberofTurnsSpinnerLabel.Text = 'Number of Turns';
            app.NumberofTurnsSpinner = uispinner(app.InputGeometricalVariablesPanel);
            app.NumberofTurnsSpinner.Step = 0.25;
            app.NumberofTurnsSpinner.Limits = [0.001 Inf];
            app.NumberofTurnsSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.NumberofTurnsSpinner.Value = 1;
            app.BraidAngleSpinnerLabel = uilabel(app.InputGeometricalVariablesPanel);
            app.BraidAngleSpinnerLabel.Text = 'Braid Angle';
            app.BraidAngleSpinner = uispinner(app.InputGeometricalVariablesPanel);
            app.BraidAngleSpinner.ValueChangingFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.BraidAngleSpinner.Limits = [1 90];
            app.BraidAngleSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.BraidAngleSpinner.Value = 45;
            app.NumberofYarnsNSpinnerLabel = uilabel(app.InputGeometricalVariablesPanel);
            app.NumberofYarnsNSpinnerLabel.Text = 'Number of Yarns (N)';
            app.NumberofYarnsNSpinner = uispinner(app.InputGeometricalVariablesPanel);
            app.NumberofYarnsNSpinner.Step = 2;
            app.NumberofYarnsNSpinner.ValueChangingFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.NumberofYarnsNSpinner.Limits = [2 Inf];
            app.NumberofYarnsNSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.NumberofYarnsNSpinner.Value = 16;
            app.DiameterofMandrelmmSpinnerLabel = uilabel(app.InputGeometricalVariablesPanel);
            app.DiameterofMandrelmmSpinnerLabel.Text = 'Diameter of Mandrel (mm)';
            app.DiameterofMandrelmmSpinner = uispinner(app.InputGeometricalVariablesPanel);
            app.DiameterofMandrelmmSpinner.ValueChangingFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.DiameterofMandrelmmSpinner.Limits = [0.001 Inf];
            app.DiameterofMandrelmmSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.DiameterofMandrelmmSpinner.Value = 20;
            app.DiameterofStrandmmSpinnerLabel = uilabel(app.InputGeometricalVariablesPanel);
            app.DiameterofStrandmmSpinnerLabel.Text = 'Diameter of Strand (mm)';
            app.DiameterofStrandmmSpinner = uispinner(app.InputGeometricalVariablesPanel);
            app.DiameterofStrandmmSpinner.Step = 0.25;
            app.DiameterofStrandmmSpinner.Limits = [0.001 Inf];
            app.DiameterofStrandmmSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.DiameterofStrandmmSpinner.Value = 1;
            app.HeightoftheTBCmmSpinnerLabel = uilabel(app.InputGeometricalVariablesPanel);
            app.HeightoftheTBCmmSpinnerLabel.Text = 'Height of the TBC (mm)';
            app.HeightoftheTBCmmSpinner = uispinner(app.InputGeometricalVariablesPanel);
            app.HeightoftheTBCmmSpinner.Step = 10;
            app.HeightoftheTBCmmSpinner.ValueChangingFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.HeightoftheTBCmmSpinner.ValueChangedFcn = createCallbackFcn(app, @HeightoftheTBCmmSpinnerValueChanged, true);
            app.HeightoftheTBCmmSpinner.Value = 0.001;
            app.LoadingXYZpointsPanel = uipanel(app.UIFigure);
            app.LoadingXYZpointsPanel.Title = 'Loading XYZ points';
            app.LoadButton = uibutton(app.LoadingXYZpointsPanel, 'state');
            app.LoadButton.ValueChangedFcn = createCallbackFcn(app, @LoadButtonValueChanged, true);
            app.LoadButton.Text = 'Load';
            app.PlotButton_2 = uibutton(app.LoadingXYZpointsPanel, 'state');
            app.PlotButton_2.ValueChangedFcn = createCallbackFcn(app, @PlotButton_2ValueChanged, true);
            app.PlotButton_2.Text = 'Plot';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.CWCheckBox = uicheckbox(app.UIFigure);
            app.CWCheckBox.ValueChangedFcn = createCallbackFcn(app, @CWCheckBoxValueChanged, true);
            app.CWCheckBox.Text = 'CW';
            app.CCWCheckBox = uicheckbox(app.UIFigure);
            app.CCWCheckBox.Text = 'CCW';
            app.Lamp = uilamp(app.UIFigure);
            app.LoadPropertiesFileButton = uibutton(app.UIFigure, 'push');
            app.LoadPropertiesFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPropertiesFileButtonPushed, true);
            app.LoadPropertiesFileButton.Text = 'Load Properties File';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plot of a Single Yarn Path')
            xlabel(app.UIAxes, 'X (mm)')
            ylabel(app.UIAxes, 'Y (mm)')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TBCYarnPathGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end